classdef telemetry_app_viewer_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        GridLayout3          matlab.ui.container.GridLayout
        SRUAVInterfaceLabel  matlab.ui.control.Label
        StartLoggingButton   matlab.ui.control.Button
        ClearLogButton       matlab.ui.control.Button
        GridLayout4          matlab.ui.container.GridLayout
        TelemetryLampLabel   matlab.ui.control.Label
        TelemetryLamp        matlab.ui.control.Lamp
        ViconLampLabel       matlab.ui.control.Label
        ViconLamp            matlab.ui.control.Lamp
        TakeoffButton        matlab.ui.control.Button
        Console              matlab.ui.control.TextArea
        LandButton           matlab.ui.control.Button
        Switch               matlab.ui.control.Switch
        SetOriginButton      matlab.ui.control.Button
        FollowRouteButton    matlab.ui.control.Button
        GridLayout5          matlab.ui.container.GridLayout
        xSpinnerLabel        matlab.ui.control.Label
        xSpinner             matlab.ui.control.Spinner
        GridLayout6          matlab.ui.container.GridLayout
        ySpinnerLabel        matlab.ui.control.Label
        ySpinner             matlab.ui.control.Spinner
        RightPanel           matlab.ui.container.Panel
        GridLayout2          matlab.ui.container.GridLayout
        HeadingIndicator     Aero.ui.control.HeadingIndicator
        HeadingLabel         matlab.ui.control.Label
        ThrottleGauge        matlab.ui.control.Gauge
        ThrottleLabel        matlab.ui.control.Label
        AttitudeAxes         matlab.ui.control.UIAxes
        AltitudeAxes         matlab.ui.control.UIAxes
        PositionAxes         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        tlm_server; % UDP Server reference
        
        vicon_client;
        vicon_hostname = '169.254.143.7:801'; % Static IP for Vicon PC and UDP port for data
        
        sample_timer;   % Timer for sampling telemetry and vicon at 200 Hz
        graph_timer;    % Timer for updating graphs at 20 Hz
        
        tlm_data = [];   % Newest telemetry data received
        vicon_data = []; % Newest vicon data received    
        
        status_tlm = false;
        status_drone = false;
        status_vicon = false;
        
        n = 100;
        
        % Variables to hold plot data
        pos_x;
        pos_y;
        pos_z;
        pos_est_x;
        pos_est_y;
        pos_est_z;
        roll;
        pitch;
        
        origin_x = 0;
        origin_y = 0;
        origin_z = 0;
        
        % Variables to hold plot lines
        pos_line;
        pos_est_line;
        alt_line;
        alt_est_line;
        roll_line;
        pitch_line;
        pos_dot;
        
        
        log_line_count = 0;
        
        % Prepare empty struct with all possible data
        log; 
        is_logging = false;
        
        log_fields = {...
            'time',...
            'gx','gy','gz', ...
            'roll','pitch','yaw', ...  % Internal attitude sensor (IMU)
            'ax','ay','az', ...     
            'vx','vy','vz', ...
            'x','y','z',  ...       % Internal position sensor (GPS)
            'vxt','vyt','vzt',  ... % Estimator
            'xt','yt','zt',  ...    % Estimator 
            'a1','a2','a3','a4','dshot',  ...
            'xe','ye','ze',  ...    % External position (VICON)
            'stat_imu','stat_flow','stat_lidar','stat_pos'  ...
        };
        
        vicon_status = 0;
        tlm_status = 0;
        vicon_enable = true; % Disable or enable vicon. Must be done if vicon is not present
        vicon_connected = false;
        
        start_pathfollow = false;
        pathfollow_timer;
        
        last_time = 0;
        
    end
    
    properties (Access = public)
 
        COMMAND_ARM        = 0;
        COMMAND_DISARM     = 1;
        COMMAND_TAKEOFF    = 2;
        COMMAND_LAND       = 3;
        COMMAND_SET_X      = 4;
        COMMAND_SET_Y      = 5;
        COMMAND_SET_Z      = 6;
        COMMAND_SET_ROLL   = 7;
        COMMAND_SET_PITCH  = 8;
        COMMAND_SET_YAW    = 9;
        COMMAND_SET_ORIGIN = 10;
        COMMAND_LOITER     = 11;
        DATA_UPDATE_POS    = 20;
        
    end

    methods (Access = private)
        % Write string to log/console textarea
        function append_to_console(app, str)
            current = get(app.Console, 'Value');
            i = 1;
            if ( current == "" )
                i = 0;
            end
            current{end+i} = [ datestr(now,'HH:MM:SS') ': ' str ];
            set(app.Console, 'Value', current);
        end
        
        % Read the newest Vicon data package
        function read_vicon(app)
            if( app.vicon_connected ) 
                
                frame = app.vicon_client.GetFrame();
                
                if( frame.Result.Value == Result.Success )
                    
                    app.vicon_status = 1;     
                    
                    pos = app.vicon_client.GetSegmentGlobalTranslation( 'SRUAV_EMIL', 'SRUAV_EMIL' );
                    app.vicon_data = pos.Translation/1000;
                    
                    x = pos.Translation(1)/1000 - app.origin_x;
                    y = pos.Translation(2)/1000 - app.origin_y;
                    z = pos.Translation(3)/1000 - app.origin_z;
                    
                    app.pos_x = shift_append_data( app, app.pos_x, x );
                    app.pos_y = shift_append_data( app, app.pos_y, y );
                    app.pos_z = shift_append_data( app, app.pos_z, z );
                   
                end
            end
        end
        
        % Clear the UDP buffer and read the newest package
        function read_telemetry(app)
            
            persistent sample_count;
            if( isempty( sample_count) )
                sample_count = 0;
            end
    
            while( app.tlm_server.NumDatagramsAvailable )
                app.tlm_status = 1;
                
                % Read one package from 
                package = read(app.tlm_server, 1);
                data = extract_tlm_package( app, package.Data );
                app.tlm_data = data;
                
                if( app.is_logging )
                    % Write a line to the log
                    log_line(app); 
                
                    if( mod(app.log_line_count, 100) == 0 )
                        append_to_console( app, sprintf('Log lines added: %d', app.log_line_count ));
                    end
                end
                
                % Only append data to plot at 40 Hz (slow down plot, but
                % increases performance         
                if( mod( sample_count, 5 ) == 0 )
                    app.pos_est_x = shift_append_data( app, app.pos_est_x, data.xt );
                    app.pos_est_y = shift_append_data( app, app.pos_est_y, data.yt );
                    app.pos_est_z = shift_append_data( app, app.pos_est_z, data.zt );
  
                    app.roll = shift_append_data( app, app.roll, data.roll * 180/pi );
                    app.pitch = shift_append_data( app, app.pitch, data.pitch * 180/pi );
                end
                
                
                sample_count = sample_count + 1;
            end
        end
        
        % Sample data from UDP and Vicon
        function data_sampler(app)
            
            persistent data_count;
            persistent last_time; 
            if( isempty( data_count ))
                data_count = 0;
                last_time = clock;
            end
            
            read_vicon(app);
            read_telemetry(app);
            
            % Send vicon data over UDP to drone at 10 Hz
            if( app.vicon_status && app.vicon_connected )
                
                if( etime(clock, last_time) >= 0.1 )
                    send_command( app, app.DATA_UPDATE_POS, [app.pos_x(end), app.pos_y(end)] );
                    last_time = clock;
                end
            end
          
            data_count = data_count + 1;
        end
        
        % Update the plots with new data
        function data_plotter(app)
            %if( app.is_logging == false )
                
                set( app.pos_line, 'XData', app.pos_x );  
                set( app.pos_line, 'YData', app.pos_y );    
                % set( app.alt_line, 'YData', app.pos_z );  
                set( app.pos_dot,  'XData', app.pos_x(end) , 'YData', app.pos_y(end));
                 
                if( isstruct( app.tlm_data ) )
                    set( app.roll_line,  'YData', app.roll );
                    set( app.pitch_line, 'YData', app.pitch );
                    
                    set( app.pos_est_line, 'XData', app.pos_est_x );  
                    set( app.pos_est_line, 'YData', app.pos_est_y ); 
                    set( app.alt_est_line, 'YData', app.pos_est_z ); 
                
                    set( app.HeadingIndicator, 'Heading', app.tlm_data.yaw * 180/pi );
                    set( app.ThrottleGauge, 'Value', app.tlm_data.dshot );
                end
                
                if( app.vicon_status == 1 )
                    app.ViconLamp.Color = 'Green';
                end
                
                if( app.tlm_status == 1 )
                    app.TelemetryLamp.Color = 'Green';
                end
                
                % Redraw plots
       
                drawnow;
            % end
        end       
        
        % Extract UDP data from drone
        function data = extract_tlm_package( ~, package )
            
            % Unpack measurements
            data.time = typecast(uint8(package(2:5)), 'uint32');  % uint32
            data.index = typecast(uint8(package(6:9)), 'uint32');  % uint32
            
            data.gx = typecast(uint8(package(10:13)), 'single');  % float32
            data.gy = typecast(uint8(package(14:17)), 'single');  % float32
            data.gz = typecast(uint8(package(18:21)), 'single'); % float32
        
            data.roll     = typecast(uint8(package(22:25)), 'single'); % float32
            data.pitch    = typecast(uint8(package(26:29)), 'single'); % float32
            data.yaw      = typecast(uint8(package(30:33)), 'single'); % float32
        
            data.ax = typecast(uint8(package(34:37)), 'single'); % float32
            data.ay = typecast(uint8(package(38:41)), 'single'); % float32
            data.az = typecast(uint8(package(42:45)), 'single'); % float32
        
            data.vx = typecast(uint8(package(46:49)), 'single'); % float32
            data.vy = typecast(uint8(package(50:53)), 'single'); % float32
            data.x = typecast(uint8(package(54:57)), 'single'); % float32
            data.y = typecast(uint8(package(58:61)), 'single'); % float32
            data.z = typecast(uint8(package(62:65)), 'single'); % float32
        
            status = package(66);
            data.stat_imu = bitget( status, 1);
            data.stat_flow = bitget( status, 2);
            data.stat_lidar = bitget( status, 3);
            data.stat_pos = bitget( status, 4);
            
            % Unpack estimates (t = tilde = estimate)
            data.xt = typecast(uint8(package(67:70)), 'single'); % float32
            data.yt = typecast(uint8(package(71:74)), 'single'); % float32
            data.zt = typecast(uint8(package(75:78)), 'single'); % float32
            
            data.vxt = typecast(uint8(package(79:82)), 'single'); % float32
            data.vyt = typecast(uint8(package(83:86)), 'single'); % float32
            data.vzt = typecast(uint8(package(87:90)), 'single'); % float32
            
            % Unpack actuation signals
            data.a1 = typecast(uint8(package(91:94)), 'single'); % float32
            data.a2 = typecast(uint8(package(95:98)), 'single'); % float32
            data.a3 = typecast(uint8(package(99:102)), 'single'); % float32
            data.a4 = typecast(uint8(package(103:106)), 'single'); % float32
            data.dshot = typecast(uint8(package(107:108)), 'uint16'); % uint16
        end
        
        % Append new data to exisiting data-array
        function data = shift_append_data(~, old_data, new_data )
            nPts = length(new_data);                % how much new data
            
            if nPts>length(old_data)                % more new data than original
              nPts = length(yData);                 % only keep last part
              new_data = new_data(end-nPts+1:nPts);
            end
            
            data = old_data;
            data(1:end-nPts) = data(nPts+1:end);    % shift old data left
            data(end-nPts+1:end) = new_data;        % new data goes on right
        end
        
        function send_command( app, command, value )
            
            data = [ uint8(command) ];
            
            if( length(value) > 1 )
                for i = 1:length(value)
                    % convert data to single (float32), and then to byte array
                    bytes = typecast( single(value(i)) ,'uint8');
                    data = [data bytes];
                end
            else
                bytes = typecast( single(value) ,'uint8');
                data = [data bytes];
            end
            
            % Send bytes over UDP til ESP32 for reconstruction
            send_raw_udp( app, data );

        end
        
        % Send an array of uint8_t values over UDP to the drone
        function send_raw_udp(app, data)
            write( app.tlm_server, data, "192.168.4.1", 8888 );
        end
        
        function log_line(app)
            
            tlm = app.tlm_data;
            if( isempty(app.vicon_data) )
                pos = zeros(3,1);
            else
                pos = app.vicon_data;
            end            
            
            % Build log line from telemetry and vicon data
            line = { ...
                tlm.time, ...
                tlm.gx, tlm.gy, tlm.gz, ...
                tlm.roll, tlm.pitch, tlm.yaw, ...
                tlm.ax, tlm.ay, tlm.az, ...
                tlm.vx, tlm.vy, 0, ...
                tlm.x, tlm.y, tlm.z, ...
                tlm.vxt, tlm.vyt, tlm.vzt, ...
                tlm.xt, tlm.yt, tlm.zt, ...
                tlm.a1, tlm.a2, tlm.a3, tlm.a4, tlm.dshot, ...
                pos(1), pos(2), pos(3), ... % vicon x, y, z from Matlab
                tlm.stat_imu, tlm.stat_flow, tlm.stat_lidar, tlm.stat_pos ...
            };
            
            data = cell2struct( line, app.log_fields, 2 );
            
            app.log(end+1) = data;
            app.log_line_count = app.log_line_count + 1;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             
            append_to_console(app, 'Booting...');
            
            app.pos_x = zeros(app.n, 1); app.pos_y = zeros(app.n, 1); app.pos_z = zeros(app.n, 1);
            app.pos_est_x = zeros(app.n, 1); app.pos_est_y = zeros(app.n, 1); app.pos_est_z = zeros(app.n, 1);
            app.roll = zeros(app.n, 1); app.pitch = zeros(app.n, 1);
            
            % Configure plots and lines
            app.pos_dot         = line(app.PositionAxes, 0, 0, 'Marker', 'o');
            app.pos_line        = line(app.PositionAxes, app.pos_x, app.pos_y );
            app.pos_est_line    = line(app.PositionAxes, app.pos_est_x, app.pos_est_y, 'Color', 'Red');
            % app.alt_line        = line(app.AltitudeAxes, 1:app.n, app.pos_z );
            app.alt_est_line    = line(app.AltitudeAxes, 1:app.n, app.pos_est_z );
            app.roll_line       = line(app.AttitudeAxes, 1:app.n, app.roll, 'Color', 'Red');
            app.pitch_line      = line(app.AttitudeAxes, 1:app.n, app.pitch, 'Color', 'Blue');
            xlim(app.PositionAxes, [-1 1]);
            xticks(app.PositionAxes, -1:.25:1);
            ylim(app.PositionAxes, [-1 1]);
            yticks(app.PositionAxes, -1:.25:1);
            
            ylim(app.AltitudeAxes, [0 1]);
            yticks(app.AltitudeAxes, 0:.2:1);
            xlim(app.AltitudeAxes, [0 app.n]);
            xticks(app.AltitudeAxes, 0:20:app.n);
           
            ylim(app.AttitudeAxes, [-10 10]);
            yticks(app.AttitudeAxes, -10:5:10);
            xlim(app.AttitudeAxes, [0 app.n]);
            xticks(app.AttitudeAxes, 0:20:app.n);

            
            % Start Telemetry UDP server
            app.tlm_server = udpport("datagram", "LocalPort", 8888);
            app.tlm_server.EnableBroadcast = true;
            append_to_console(app, 'UDP Server Started.');
            
            
            % Start Vicon Client
            Client.LoadViconDataStreamSDK();
            app.vicon_client = Client();
            app.vicon_client.Connect( app.vicon_hostname );
            
            
            if( app.vicon_enable )
                
                if( app.vicon_client.IsConnected().Connected )
                    % Configure Vicon Client
                    app.vicon_client.EnableSegmentData();                                               % Enable translation, rotation etc...
                    app.vicon_client.SetBufferSize(1);                                                  % Always buffer newest 
                    app.vicon_client.SetStreamMode( StreamMode.ServerPush );                            % Set the streaming mode
                    
                    % This depends very much on how the Vicon / Positioning
                    % system is calibrated. So change if position is wrong.
                    app.vicon_client.SetAxisMapping( Direction.Left, Direction.Backward, Direction.Up ); % Configure axis direction
                    
                    append_to_console(app, 'Vicon Connected.');
                    
                    app.vicon_connected = true;
                else
                    append_to_console(app, 'Vicon Not Connected.');
                    app.vicon_connected = false;
                end
            end

            
            % Start timer to read telemetry and vicon data (200hz)
            app.sample_timer = timer('Period', 0.005, 'ExecutionMode', 'fixedSpacing', 'TasksToExecute', Inf);
            app.sample_timer.TimerFcn = @(~,~)data_sampler(app);
            start(app.sample_timer);
            
            
            % Start timer to update plots (10hz)
            app.graph_timer = timer('Period', 0.1, 'ExecutionMode', 'fixedSpacing', 'TasksToExecute', Inf);
            app.graph_timer.TimerFcn = @(~,~)data_plotter(app);
            start(app.graph_timer);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.sample_timer);
            stop(app.graph_timer);
            
            % Make sure all timers are DEAD
            listOfTimers = timerfindall;
            if ~isempty(listOfTimers)
                delete(listOfTimers(:));
            end
               
            delete(app)
        end

        % Button pushed function: SetOriginButton
        function SetOriginButtonPushed(app, event)
            send_command(app, app.COMMAND_SET_ORIGIN, 0);
            
            if( isempty(app.vicon_data) == false )
                app.origin_x = app.vicon_data(1);
                app.origin_y = app.vicon_data(2);
                app.origin_z = app.vicon_data(3);
            end
        end

        % Button pushed function: TakeoffButton
        function TakeoffButtonPushed(app, event)
            % Takeoff command includes the targeted altitude :-)
            send_command(app, app.COMMAND_TAKEOFF, 0.5 ); 
        end

        % Button pushed function: LandButton
        function LandButtonPushed(app, event)
            send_command(app, app.COMMAND_LAND, 0 );
        end

        % Value changed function: xSpinner
        function xSpinnerValueChanged(app, event)
            value = app.xSpinner.Value;
            
            send_command(app, app.COMMAND_SET_X, value );
        end

        % Value changed function: ySpinner
        function ySpinnerValueChanged(app, event)
            value = app.ySpinner.Value;
            
            send_command(app, app.COMMAND_SET_Y, value );
        end

        % Button pushed function: StartLoggingButton
        function StartLoggingButtonPushed(app, event)
            
            if( app.is_logging == false )
                % Start logging 
                app.is_logging = true;

                app.log_line_count = 0;
                app.StartLoggingButton.Text = 'Stop Logging';
            else
                
                % Stop logging
                app.is_logging = false;
                
                % Save log to workspace
                assignin('base', 'data', app.log);
                app.StartLoggingButton.Text = 'Start Logging';
            end
            
            % Prepare new log structure
            app.log = struct( ...
                'time',{}, ...
                'gx',{},'gy',{}, 'gz',{}, ...       % Onboard Sensors
                'roll',{},'pitch',{}, 'yaw',{}, ... % ...
                'ax',{},'ay',{}, 'az',{}, ...       % ...
                'vx',{},'vy',{}, 'vz',{}, ...       % ...
                'x',{},'y',{},'z',{}, ...           % ...
                'vxt',{},'vyt',{}, 'vzt',{}, ...    % Onboard estimator 
                'xt',{},'yt',{}, 'zt',{}, ...       % ...                
                'a1',{},'a2',{}, 'a3',{}, 'a4',{}, 'dshot',{}, ...  % Onboard control signals
                'xe',{},'ye',{},'ze',{}, ...        % External Position Measurements
                'stat_imu',{}, ...                  % Sensor status
                'stat_flow',{}, ...                 % ...
                'stat_lidar',{}, ...                % ...
                'stat_pos',{} ...                   % ...
            );
            
        end

        % Button pushed function: FollowRouteButton
        function FollowRouteButtonPushed(app, event)
            send_command(app, app.COMMAND_LOITER, 0);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {634, 634};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {299, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.SRUAVInterfaceLabel = uilabel(app.GridLayout3);
            app.SRUAVInterfaceLabel.Text = 'SR-UAV Interface';
            app.StartLoggingButton = uibutton(app.GridLayout3, 'push');
            app.StartLoggingButton.ButtonPushedFcn = createCallbackFcn(app, @StartLoggingButtonPushed, true);
            app.StartLoggingButton.Text = 'Start Logging';
            app.ClearLogButton = uibutton(app.GridLayout3, 'push');
            app.ClearLogButton.Text = 'Clear Log';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.TelemetryLampLabel = uilabel(app.GridLayout4);
            app.TelemetryLampLabel.Text = 'Telemetry';
            app.TelemetryLamp = uilamp(app.GridLayout4);
            app.ViconLampLabel = uilabel(app.GridLayout4);
            app.ViconLampLabel.Text = 'Vicon';
            app.ViconLamp = uilamp(app.GridLayout4);
            app.TakeoffButton = uibutton(app.GridLayout3, 'push');
            app.TakeoffButton.ButtonPushedFcn = createCallbackFcn(app, @TakeoffButtonPushed, true);
            app.TakeoffButton.Text = 'Takeoff';
            app.Console = uitextarea(app.GridLayout3);
            app.LandButton = uibutton(app.GridLayout3, 'push');
            app.LandButton.ButtonPushedFcn = createCallbackFcn(app, @LandButtonPushed, true);
            app.LandButton.Text = 'Land';
            app.Switch = uiswitch(app.GridLayout3, 'slider');
            app.Switch.Items = {'Off', 'Armed'};
            app.SetOriginButton = uibutton(app.GridLayout3, 'push');
            app.SetOriginButton.ButtonPushedFcn = createCallbackFcn(app, @SetOriginButtonPushed, true);
            app.SetOriginButton.Text = 'Set Origin (0,0)';
            app.FollowRouteButton = uibutton(app.GridLayout3, 'push');
            app.FollowRouteButton.ButtonPushedFcn = createCallbackFcn(app, @FollowRouteButtonPushed, true);
            app.FollowRouteButton.Text = 'Follow Route';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.xSpinnerLabel = uilabel(app.GridLayout5);
            app.xSpinnerLabel.Text = 'x';
            app.xSpinner = uispinner(app.GridLayout5);
            app.xSpinner.Step = 0.1;
            app.xSpinner.ValueChangedFcn = createCallbackFcn(app, @xSpinnerValueChanged, true);
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.ySpinnerLabel = uilabel(app.GridLayout6);
            app.ySpinnerLabel.Text = 'y';
            app.ySpinner = uispinner(app.GridLayout6);
            app.ySpinner.Step = 0.1;
            app.ySpinner.ValueChangedFcn = createCallbackFcn(app, @ySpinnerValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.HeadingIndicator = uiaeroheading(app.GridLayout2);
            app.HeadingIndicator.Heading = 90;
            app.HeadingLabel = uilabel(app.GridLayout2);
            app.HeadingLabel.Text = 'Heading';
            app.ThrottleGauge = uigauge(app.GridLayout2, 'circular');
            app.ThrottleGauge.Limits = [0 2000];
            app.ThrottleGauge.MajorTicks = [0 500 1000 1500 2000];
            app.ThrottleLabel = uilabel(app.GridLayout2);
            app.ThrottleLabel.Text = 'Throttle';
            app.AttitudeAxes = uiaxes(app.GridLayout2);
            title(app.AttitudeAxes, 'Attitude')
            xlabel(app.AttitudeAxes, 'Samples')
            ylabel(app.AttitudeAxes, 'Angle [deg]')
            zlabel(app.AttitudeAxes, 'Z')
            app.AttitudeAxes.XTick = [];
            app.AttitudeAxes.XTickLabel = '';
            app.AttitudeAxes.YTick = [];
            app.AltitudeAxes = uiaxes(app.GridLayout2);
            title(app.AltitudeAxes, 'Altitude')
            xlabel(app.AltitudeAxes, 'Samples')
            ylabel(app.AltitudeAxes, 'Heigth [m]')
            zlabel(app.AltitudeAxes, 'Z')
            app.AltitudeAxes.XTick = [];
            app.AltitudeAxes.XTickLabel = '';
            app.AltitudeAxes.YTick = [];
            app.PositionAxes = uiaxes(app.GridLayout2);
            title(app.PositionAxes, 'Position')
            xlabel(app.PositionAxes, 'X [m]')
            ylabel(app.PositionAxes, 'Y [m]')
            zlabel(app.PositionAxes, 'Z')
            app.PositionAxes.XTick = [];
            app.PositionAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = telemetry_app_viewer_v2

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end