classdef telemetry_app_viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Servo4Gauge                matlab.ui.control.Gauge
        Servo4GaugeLabel           matlab.ui.control.Label
        Servo1Gauge                matlab.ui.control.Gauge
        Servo1GaugeLabel           matlab.ui.control.Label
        Servo3Gauge                matlab.ui.control.Gauge
        Servo3GaugeLabel           matlab.ui.control.Label
        Servo2Gauge                matlab.ui.control.Gauge
        Servo2GaugeLabel           matlab.ui.control.Label
        ThrottleGauge              matlab.ui.control.SemicircularGauge
        ThrottleGaugeLabel         matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        StartLoggingButton         matlab.ui.control.Button
        ClearButton                matlab.ui.control.Button
        SRUAVTelemetryViewerLabel  matlab.ui.control.Label
        StopButton                 matlab.ui.control.Button
        ArmMotorsButton            matlab.ui.control.Button
        VelocityPlot               matlab.ui.control.UIAxes
        AttitudePlot               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        udp_server;
        log_line = 1;
        
        tlm_timer;
        
        att_plot;
        vel_plot;
        
        roll_line;
        pitch_line;

        vx_line;
        vy_line;
        vz_line;
        
        data = struct( ...
            'gx',{},'gy',{}, 'gz',{}, ...
            'roll',{},'pitch',{}, 'yaw',{}, ...
            'ax',{},'ay',{}, 'az',{}, ...
            'vx',{},'vy',{}, 'z',{}, ...
            'status_imu',{},'status_flow',{}, 'status_lidar',{}, ...
            'xe',{},'ye',{}, 'ze',{}, ...
            'vxe',{},'vye',{}, 'vze',{}, ...
            'a1',{},'a2',{}, 'a3',{}, 'a4',{}, 'dshot',{} ...
        );
        
        is_logging = false;
        log_index = 1;
    end
    
    methods (Access = private)
        
        function append_to_log(app, string)
            
            % app.LogText.Value{app.log_line} = strcat(datestr(now,'HH:MM'), ": ", string, "\n");
            % app.log_line = app.log_line + 1;
            
            % app.LogText.Value
            
        end
        
        function read_telemetry(app)
            
            roll = 0;
            pitch = 0;
            vx = 0;
            vy = 0;
            vz = 0;
            
            index = 1;
            
            while( app.udp_server.NumDatagramsAvailable )
                
                
                % Read one package from 
                package = read(app.udp_server, 1);
                dat = extract_tlm_package( app, package.Data );
                
                app.Servo1Gauge.Value = dat.a1;
                app.Servo2Gauge.Value = dat.a2;
                app.Servo3Gauge.Value = dat.a3;
                app.Servo4Gauge.Value = dat.a4;
                
                app.ThrottleGauge.Value = dat.dshot;
                
                roll(index) = dat.roll * 180/pi;
                pitch(index) = dat.pitch * 180/pi;
                vx(index) = dat.vxe;
                vy(index) = dat.vye;
                vz(index) = dat.vze;
                
                index = index + 1;
                
                if( app.is_logging )
                    app.data(app.log_index) = dat;
                    app.log_index = app.log_index + 1;
                end
            end
            
            % Draw plots
            
            stripchart('Update',app.roll_line, roll  );
            stripchart('Update',app.pitch_line, pitch);
            % stripchart('Update',app.yaw_line, data.yaw * 180/pi );
            
            
            stripchart('Update',app.vx_line, vx );
            stripchart('Update',app.vy_line, vy );
            stripchart('Update',app.vz_line, vz );

            
        end
        
        function data = extract_tlm_package( app, package )
            % Unpack measurements
            data.gx = typecast(uint8(package(2:5)), 'single');  % float32
            data.gy = typecast(uint8(package(6:9)), 'single');  % float32
            data.gz = typecast(uint8(package(10:13)), 'single'); % float32
        
            data.roll     = typecast(uint8(package(14:17)), 'single'); % float32
            data.pitch    = typecast(uint8(package(18:21)), 'single'); % float32
            data.yaw      = typecast(uint8(package(22:25)), 'single'); % float32
        
            data.ax = typecast(uint8(package(26:29)), 'single'); % float32
            data.ay = typecast(uint8(package(30:33)), 'single'); % float32
            data.az = typecast(uint8(package(34:37)), 'single'); % float32
        
            data.vx = typecast(uint8(package(38:41)), 'single'); % float32
            data.vy = typecast(uint8(package(42:45)), 'single'); % float32
            data.z = typecast(uint8(package(46:49)), 'single'); % float32
        
            status = package(50);
            data.status_imu = bitget( status, 1);
            data.status_flow = bitget( status, 2);
            data.status_lidar = bitget( status, 3);
            
            % Unpack estimates
            data.xe = typecast(uint8(package(51:54)), 'single'); % float32
            data.ye = typecast(uint8(package(55:58)), 'single'); % float32
            data.ze = typecast(uint8(package(59:62)), 'single'); % float32
            
            data.vxe = typecast(uint8(package(63:66)), 'single'); % float32
            data.vye = typecast(uint8(package(67:70)), 'single'); % float32
            data.vze = typecast(uint8(package(71:74)), 'single'); % float32
            
            % Unpack actuation signals
            data.a1 = typecast(uint8(package(75:78)), 'single'); % float32
            data.a2 = typecast(uint8(package(79:82)), 'single'); % float32
            data.a3 = typecast(uint8(package(83:86)), 'single'); % float32
            data.a4 = typecast(uint8(package(87:90)), 'single'); % float32
            data.dshot = typecast(uint8(package(91:92)), 'uint16'); % uint16
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Start UDP server
            app.udp_server = udpport("datagram", "LocalPort", 8888);

            append_to_log(app, "UDP Server started");
            
            
            app.tlm_timer = timer('Period',0.05, 'ExecutionMode', 'fixedSpacing', 'TasksToExecute', Inf);
            app.tlm_timer.TimerFcn = @(~,~)app.read_telemetry();
            start(app.tlm_timer);
            
            
            % Prepare stripcharts
            
            app.att_plot = app.AttitudePlot;
            app.vel_plot = app.VelocityPlot;
            
            ylim(app.att_plot, [-30, 30]);
            ylim(app.vel_plot, [-1, 1]);
            
            app.roll_line   = line(app.att_plot, 1:200,zeros(200,1) , 'Color', 'Red');
            app.pitch_line  = line(app.att_plot, 1:200,zeros(200,1) , 'Color', 'Blue');
            
            app.vx_line     = line(app.vel_plot, 1:200,zeros(200,1) , 'Color', 'Red');
            app.vy_line     = line(app.vel_plot, 1:200,zeros(200,1) , 'Color', 'Blue');
            app.vz_line     = line(app.vel_plot, 1:200,zeros(200,1) , 'Color', 'Green');
            % stripchart('Initialize', app.att_plot, 'samples');
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            stop(app.tlm_timer);
            
            % Make sure all timers are DEAD
            listOfTimers = timerfindall
            if ~isempty(listOfTimers)
                delete(listOfTimers(:));
            end
            
            delete(app)
            
        end

        % Button pushed function: StartLoggingButton
        function StartLoggingButtonPushed(app, event)
            app.is_logging = true;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.is_logging = false;
            assignin('base', 'data', app.data);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.log_index = 1;
            app.data = struct( ...
                'gx',{},'gy',{}, 'gz',{}, ...
                'roll',{},'pitch',{}, 'yaw',{}, ...
                'ax',{},'ay',{}, 'az',{}, ...
                'vx',{},'vy',{}, 'z',{}, ...
                'status_imu',{},'status_flow',{}, 'status_lidar',{}, ...
                'xe',{},'ye',{}, 'ze',{}, ...
                'vxe',{},'vye',{}, 'vze',{}, ...
                'a1',{},'a2',{}, 'a3',{}, 'a4',{}, 'dshot',{} ...
            );
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Servo4Gauge = uigauge(app.UIFigure, 'circular');
            app.Servo4Gauge.Limits = [-30 30];
            app.Servo4GaugeLabel = uilabel(app.UIFigure);
            app.Servo4GaugeLabel.Text = 'Servo 4';
            app.Servo1Gauge = uigauge(app.UIFigure, 'circular');
            app.Servo1Gauge.Limits = [-30 30];
            app.Servo1GaugeLabel = uilabel(app.UIFigure);
            app.Servo1GaugeLabel.Text = 'Servo 1';
            app.Servo3Gauge = uigauge(app.UIFigure, 'circular');
            app.Servo3Gauge.Limits = [-30 30];
            app.Servo3GaugeLabel = uilabel(app.UIFigure);
            app.Servo3GaugeLabel.Text = 'Servo 3';
            app.Servo2Gauge = uigauge(app.UIFigure, 'circular');
            app.Servo2Gauge.Limits = [-30 30];
            app.Servo2GaugeLabel = uilabel(app.UIFigure);
            app.Servo2GaugeLabel.Text = 'Servo 2';
            app.ThrottleGauge = uigauge(app.UIFigure, 'semicircular');
            app.ThrottleGauge.Limits = [0 2000];
            app.ThrottleGaugeLabel = uilabel(app.UIFigure);
            app.ThrottleGaugeLabel.Text = 'Throttle';
            app.Panel = uipanel(app.UIFigure);
            app.StartLoggingButton = uibutton(app.Panel, 'push');
            app.StartLoggingButton.ButtonPushedFcn = createCallbackFcn(app, @StartLoggingButtonPushed, true);
            app.StartLoggingButton.Text = 'Start Logging';
            app.ClearButton = uibutton(app.Panel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.SRUAVTelemetryViewerLabel = uilabel(app.Panel);
            app.SRUAVTelemetryViewerLabel.Text = 'SR-UAV Telemetry Viewer';
            app.StopButton = uibutton(app.Panel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ArmMotorsButton = uibutton(app.Panel, 'push');
            app.ArmMotorsButton.Text = 'Arm Motors';
            app.VelocityPlot = uiaxes(app.UIFigure);
            title(app.VelocityPlot, 'Velocity')
            xlabel(app.VelocityPlot, 'Samples [-]')
            ylabel(app.VelocityPlot, 'Velocity [m/s]')
            zlabel(app.VelocityPlot, 'Z')
            app.AttitudePlot = uiaxes(app.UIFigure);
            title(app.AttitudePlot, 'Attitude')
            xlabel(app.AttitudePlot, 'Samples [-]')
            ylabel(app.AttitudePlot, 'Angle [degress]')
            zlabel(app.AttitudePlot, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = telemetry_app_viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end