classdef telemetry_app_viewer_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        GridLayout3          matlab.ui.container.GridLayout
        SRUAVInterfaceLabel  matlab.ui.control.Label
        StartLoggingButton   matlab.ui.control.Button
        ClearLogButton       matlab.ui.control.Button
        GridLayout4          matlab.ui.container.GridLayout
        TelemetryLampLabel   matlab.ui.control.Label
        TelemetryLamp        matlab.ui.control.Lamp
        ViconLampLabel       matlab.ui.control.Label
        ViconLamp            matlab.ui.control.Lamp
        StatusLampLabel      matlab.ui.control.Label
        StatusLamp           matlab.ui.control.Lamp
        TakeoffButton        matlab.ui.control.Button
        Console              matlab.ui.control.TextArea
        LandButton           matlab.ui.control.Button
        ListBox              matlab.ui.control.ListBox
        LoadSetpointsButton  matlab.ui.control.Button
        Switch               matlab.ui.control.Switch
        RightPanel           matlab.ui.container.Panel
        GridLayout2          matlab.ui.container.GridLayout
        HeadingIndicator     Aero.ui.control.HeadingIndicator
        HeadingLabel         matlab.ui.control.Label
        PositionAxes         matlab.ui.control.UIAxes
        AltitudeAxes         matlab.ui.control.UIAxes
        AttitudeAxes         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        udp_server; % UDP Server reference
    end
    
    methods (Access = private)
        
        function append_to_console(app, str)
            current = get(app.Console, 'Value');
            n = 1;
            if ( current == "" )
                n = 0;
            end
            current{end+n} = [ datestr(now,'HH:MM:SS') ': ' str ];
            set(app.Console, 'Value', current);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             
            append_to_console(app, 'Booting...')
            
            % Start UDP server
            app.udp_server = udpport("datagram", "LocalPort", 8888);
            
            append_to_console(app, 'UDP Server started.')
            
%             app.tlm_timer = timer('Period',0.05, 'ExecutionMode', 'fixedSpacing', 'TasksToExecute', Inf);
%             app.tlm_timer.TimerFcn = @(~,~)app.read_telemetry();
%             start(app.tlm_timer);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {634, 634};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {299, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.SRUAVInterfaceLabel = uilabel(app.GridLayout3);
            app.SRUAVInterfaceLabel.Text = 'SR-UAV Interface';
            app.StartLoggingButton = uibutton(app.GridLayout3, 'push');
            app.StartLoggingButton.Text = 'Start Logging';
            app.ClearLogButton = uibutton(app.GridLayout3, 'push');
            app.ClearLogButton.Text = 'Clear Log';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.TelemetryLampLabel = uilabel(app.GridLayout4);
            app.TelemetryLampLabel.Text = 'Telemetry';
            app.TelemetryLamp = uilamp(app.GridLayout4);
            app.ViconLampLabel = uilabel(app.GridLayout4);
            app.ViconLampLabel.Text = 'Vicon';
            app.ViconLamp = uilamp(app.GridLayout4);
            app.StatusLampLabel = uilabel(app.GridLayout4);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.GridLayout4);
            app.TakeoffButton = uibutton(app.GridLayout3, 'push');
            app.TakeoffButton.Text = 'Takeoff';
            app.Console = uitextarea(app.GridLayout3);
            app.LandButton = uibutton(app.GridLayout3, 'push');
            app.LandButton.Text = 'Land';
            app.ListBox = uilistbox(app.GridLayout3);
            app.LoadSetpointsButton = uibutton(app.GridLayout3, 'push');
            app.LoadSetpointsButton.Text = 'Load Setpoints';
            app.Switch = uiswitch(app.GridLayout3, 'slider');
            app.Switch.Items = {'Off', 'Armed'};
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.HeadingIndicator = uiaeroheading(app.GridLayout2);
            app.HeadingLabel = uilabel(app.GridLayout2);
            app.HeadingLabel.Text = 'Heading';
            app.PositionAxes = uiaxes(app.GridLayout2);
            title(app.PositionAxes, 'Position')
            xlabel(app.PositionAxes, 'X [m]')
            ylabel(app.PositionAxes, 'Y [m]')
            zlabel(app.PositionAxes, 'Z')
            app.AltitudeAxes = uiaxes(app.GridLayout2);
            title(app.AltitudeAxes, 'Altitude')
            xlabel(app.AltitudeAxes, 'Time [s]')
            ylabel(app.AltitudeAxes, 'Y')
            zlabel(app.AltitudeAxes, 'Z')
            app.AttitudeAxes = uiaxes(app.GridLayout2);
            title(app.AttitudeAxes, 'Attitude')
            xlabel(app.AttitudeAxes, 'Time [s]')
            ylabel(app.AttitudeAxes, 'Angle [deg]')
            zlabel(app.AttitudeAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = telemetry_app_viewer_v2

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end