classdef telemetry_app_viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Servo4Gauge                     matlab.ui.control.Gauge
        Servo4GaugeLabel                matlab.ui.control.Label
        Servo1Gauge                     matlab.ui.control.Gauge
        Servo1GaugeLabel                matlab.ui.control.Label
        Servo3Gauge                     matlab.ui.control.Gauge
        Servo3GaugeLabel                matlab.ui.control.Label
        Servo2Gauge                     matlab.ui.control.Gauge
        Servo2GaugeLabel                matlab.ui.control.Label
        ThrottleGauge                   matlab.ui.control.SemicircularGauge
        ThrottleGaugeLabel              matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        ArmSystemButton                 matlab.ui.control.Button
        StartLoggingButton              matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        AltitudeSetpointEditFieldLabel  matlab.ui.control.Label
        AltitudeSetpointInput           matlab.ui.control.NumericEditField
        LogText                         matlab.ui.control.TextArea
        PositionPlot                    matlab.ui.control.UIAxes
        AttitudePlot                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        udp_server;
        log_line = 1;
        
        tlm_timer;
    end
    
    methods (Access = private)
        
        function append_to_log(app, string)
            
            % app.LogText.Value{app.log_line} = strcat(datestr(now,'HH:MM'), ": ", string, "\n");
            % app.log_line = app.log_line + 1;
            
            % app.LogText.Value
            
        end
        
        function read_telemetry(app)
            
            while( app.udp_server.NumDatagramsAvailable )
             
                % Read one package from 
                package = read(app.udp_server, 1);
                data = extract_tlm_package( app, package.Data );
                
                app.Servo1Gauge.Value = data.a1;
                app.Servo2Gauge.Value = data.a2;
                app.Servo3Gauge.Value = data.a3;
                app.Servo4Gauge.Value = data.a4;
                
                app.ThrottleGauge.Value = data.dshot;
            end
            
            
        end
        
        function data = extract_tlm_package( app, package )
            % Unpack measurements
            data.gx = typecast(uint8(package(2:5)), 'single');  % float32
            data.gy = typecast(uint8(package(6:9)), 'single');  % float32
            data.gz = typecast(uint8(package(10:13)), 'single'); % float32
        
            data.roll     = typecast(uint8(package(14:17)), 'single'); % float32
            data.pitch    = typecast(uint8(package(18:21)), 'single'); % float32
            data.yaw      = typecast(uint8(package(22:25)), 'single'); % float32
        
            data.ax = typecast(uint8(package(26:29)), 'single'); % float32
            data.ay = typecast(uint8(package(30:33)), 'single'); % float32
            data.az = typecast(uint8(package(34:37)), 'single'); % float32
        
            data.vx = typecast(uint8(package(38:41)), 'single'); % float32
            data.vy = typecast(uint8(package(42:45)), 'single'); % float32
            data.z = typecast(uint8(package(46:49)), 'single'); % float32
        
            status = package(50);
            data.status_imu = bitget( status, 1);
            data.status_flow = bitget( status, 2);
            data.status_lidar = bitget( status, 3);
            
            % Unpack estimates
            data.xe = typecast(uint8(package(51:54)), 'single'); % float32
            data.ye = typecast(uint8(package(55:58)), 'single'); % float32
            data.ze = typecast(uint8(package(59:62)), 'single'); % float32
            
            data.vxe = typecast(uint8(package(63:66)), 'single'); % float32
            data.vye = typecast(uint8(package(67:70)), 'single'); % float32
            data.vze = typecast(uint8(package(71:74)), 'single'); % float32
            
            % Unpack actuation signals
            data.a1 = typecast(uint8(package(75:78)), 'single'); % float32
            data.a2 = typecast(uint8(package(79:82)), 'single'); % float32
            data.a3 = typecast(uint8(package(83:86)), 'single'); % float32
            data.a4 = typecast(uint8(package(87:90)), 'single'); % float32
            data.dshot = typecast(uint8(package(91:92)), 'uint16'); % uint16
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Start UDP server
            app.udp_server = udpport("datagram", "LocalPort", 8888);

            append_to_log(app, "UDP Server started");
            
            
            app.tlm_timer = timer('Period',0.01, 'ExecutionMode', 'fixedSpacing', 'TasksToExecute', Inf);
            app.tlm_timer.TimerFcn = @(~,~)app.read_telemetry();
            start(app.tlm_timer);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            stop(app.tlm_timer);
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Servo4Gauge = uigauge(app.UIFigure, 'circular');
            app.Servo4Gauge.Limits = [-30 30];
            app.Servo4GaugeLabel = uilabel(app.UIFigure);
            app.Servo4GaugeLabel.Text = 'Servo 4';
            app.Servo1Gauge = uigauge(app.UIFigure, 'circular');
            app.Servo1Gauge.Limits = [-30 30];
            app.Servo1GaugeLabel = uilabel(app.UIFigure);
            app.Servo1GaugeLabel.Text = 'Servo 1';
            app.Servo3Gauge = uigauge(app.UIFigure, 'circular');
            app.Servo3Gauge.Limits = [-30 30];
            app.Servo3GaugeLabel = uilabel(app.UIFigure);
            app.Servo3GaugeLabel.Text = 'Servo 3';
            app.Servo2Gauge = uigauge(app.UIFigure, 'circular');
            app.Servo2Gauge.Limits = [-30 30];
            app.Servo2GaugeLabel = uilabel(app.UIFigure);
            app.Servo2GaugeLabel.Text = 'Servo 2';
            app.ThrottleGauge = uigauge(app.UIFigure, 'semicircular');
            app.ThrottleGauge.Limits = [0 2000];
            app.ThrottleGaugeLabel = uilabel(app.UIFigure);
            app.ThrottleGaugeLabel.Text = 'Throttle';
            app.Panel = uipanel(app.UIFigure);
            app.ArmSystemButton = uibutton(app.Panel, 'push');
            app.ArmSystemButton.Text = 'Arm System';
            app.StartLoggingButton = uibutton(app.Panel, 'push');
            app.StartLoggingButton.Text = 'Start Logging';
            app.ClearButton = uibutton(app.Panel, 'push');
            app.ClearButton.Text = 'Clear';
            app.AltitudeSetpointEditFieldLabel = uilabel(app.Panel);
            app.AltitudeSetpointEditFieldLabel.Text = 'Altitude Setpoint';
            app.AltitudeSetpointInput = uieditfield(app.Panel, 'numeric');
            app.LogText = uitextarea(app.Panel);
            app.PositionPlot = uiaxes(app.UIFigure);
            title(app.PositionPlot, 'Position')
            xlabel(app.PositionPlot, 'X')
            ylabel(app.PositionPlot, 'Y')
            zlabel(app.PositionPlot, 'Z')
            app.AttitudePlot = uiaxes(app.UIFigure);
            title(app.AttitudePlot, 'Attitude')
            xlabel(app.AttitudePlot, 'X')
            ylabel(app.AttitudePlot, 'Y')
            zlabel(app.AttitudePlot, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = telemetry_app_viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end